classdef FLHZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        Button_GetFile        matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        EditField             matlab.ui.control.EditField
        Button_19             matlab.ui.control.Button
        Button_18             matlab.ui.control.Button
        Button_17             matlab.ui.control.Button
        Label_10              matlab.ui.control.Label
        EditField_3           matlab.ui.control.EditField
        CheckBox              matlab.ui.control.CheckBox
        Panel_4               matlab.ui.container.Panel
        Label_11              matlab.ui.control.Label
        EditField_4           matlab.ui.control.EditField
        Label_12              matlab.ui.control.Label
        EditField_5           matlab.ui.control.EditField
        Label_13              matlab.ui.control.Label
        EditField_6           matlab.ui.control.EditField
        Button_SheetFromFile  matlab.ui.control.Button
        Label_14              matlab.ui.control.Label
        DropDown_DataSheet    matlab.ui.control.DropDown
        Panel_2               matlab.ui.container.Panel
        Button_3              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Button_5              matlab.ui.control.Button
        Button_6              matlab.ui.control.Button
        Label_3               matlab.ui.control.Label
        ListBox               matlab.ui.control.ListBox
        Label_4               matlab.ui.control.Label
        ListBox_2             matlab.ui.control.ListBox
        Label_5               matlab.ui.control.Label
        ListBox_3             matlab.ui.control.ListBox
        Panel_3               matlab.ui.container.Panel
        Button_11             matlab.ui.control.Button
        Button_14             matlab.ui.control.Button
        Label_8               matlab.ui.control.Label
        Label_9               matlab.ui.control.Label
        Button_16             matlab.ui.control.Button
        Label_6               matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        Label_7               matlab.ui.control.Label
        ListBox_4             matlab.ui.control.ListBox
        Button_20             matlab.ui.control.Button
    end

    
    properties (Access = private)
        allvariables % 所有变量名
        mylist_unused MYLIST
        mylist_class MYLIST
        mylist_data MYLIST
        mylist_con  MYLIST
        controlt% 无序的data控制信息
        control %data控制信息

        rowformat1 % Description
        rowformat2 % Description
        rowformat3 % Description
    end

    methods (Access = private)
    
        function r = FormStr(obj,c)
            
            %将字符串细胞形成字符串储存
            r='{';
            for it=1:length(c)-1
               r=[r '''' c{it} ''','];
            end
            it=length(c);
            r=[r '''' c{it} '''}'];
        end
        
        function r = FormStr2(obj,c)
            r='{';
            for it=1:size(c,1)
                r=[r '''' c{it,1} ''',''' c{it,2} ''';'];
                
            end
            r=[r '}'];
        end
        
        
        function results = PathRewrite(app,str)
            %fprintf需要对斜杆进行处理
            results='';
            for ch=str
                if '\'~=ch
                    results=[results ch];
                else
                    results=[results '\\'];
                end
                
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mylist_unused=MYLIST(app.ListBox);
            app.mylist_class=MYLIST(app.ListBox_2);
            app.mylist_data=MYLIST(app.ListBox_3);
            app.mylist_con=MYLIST(app.ListBox_4);
            app.controlt={};
            app.control={};
            app.rowformat1=[];
            app.rowformat2=[];
            app.rowformat3=[];
        end

        % Button pushed function: Button_GetFile
        function Button_GetFilePushed(app, event)
            [filename,path]=uigetfile('*.xlsx');
            if filename~=0
                set(app.EditField,'Value',[path  filename]);
            end
            
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %获取变量名
            
            %检查是否自己制定了数据格式
            if isempty(app.rowformat1)&&isempty(app.rowformat2)&&isempty(app.rowformat3)
                app.rowformat1=1;%没有指定 那么按照无注释行处理
                app.rowformat2=[];
                app.rowformat3=2;
            end
            app.mylist_unused.Clear();
            xlsname=app.EditField.Value;
            sheet=app.DropDown_DataSheet.Value;
            [~,~,raw]=xlsread(xlsname,sheet);
            app.allvariables=raw(app.rowformat1,:);
            app.mylist_unused.Append(app.allvariables);
            %             app.ListBox.Items=app.allvariables;
            %             app.ListBox.ItemsData=1:length(app.ListBox.Items);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if isempty(app.mylist_unused.uilb.Value)
                return;
            end
            app.mylist_class.Append(app.mylist_unused.items(app.mylist_unused.uilb.Value));
            app.mylist_unused.DeleteSelection();
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if isempty(app.mylist_unused.uilb.Value)
                return;
            end
            app.mylist_data.Append(app.mylist_unused.items(app.mylist_unused.uilb.Value));
%             app.controlt=[app.controlt;app.mylist_unused.GetFirstSelection,{[]}];
            app.mylist_unused.DeleteSelection();
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if isempty(app.mylist_class.uilb.Value)
                return;
            end
            app.mylist_unused.Append(app.mylist_class.items(app.mylist_class.uilb.Value));
            app.mylist_class.DeleteSelection();
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %删除一行
            if isempty(app.mylist_data.uilb.Value)
                return;
            end
            app.mylist_unused.Append(app.mylist_data.items(app.mylist_data.uilb.Value));
            t=app.mylist_data.GetFirstSelection();
            indext=[];
            for it=1:size(app.controlt,1)%删除控制信息 用变量名寻找
                if strcmp(t,app.controlt{it,1})
                    indext=[indext it];
                end
            end
            app.controlt(indext,:)=[];
            app.mylist_data.DeleteSelection();
        end

        % Callback function
        function Button_7Pushed(app, event)
            app.mylist_class.MoveUp();
        end

        % Callback function
        function Button_9Pushed(app, event)
            app.mylist_class.MoveDown();
        end

        % Callback function
        function Button_8Pushed(app, event)
            app.mylist_data.MoveUp();
        end

        % Callback function
        function Button_10Pushed(app, event)
            app.mylist_data.MoveDown();
        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            %改变控制变量
            app.Label_9.Text=app.mylist_data.GetFirstSelection();
            t=app.mylist_data.GetFirstSelection();t=t{1};
            app.mylist_con.Clear();
            for it=1:size(app.controlt,1)%寻找控制信息 用变量名寻
                if strcmp(t,app.controlt{it,1})
                    c=app.controlt{it,2};
                    app.mylist_con.Append(c);
                end
            end
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            %手动刷新
            app.Label_9.Text=app.mylist_data.GetFirstSelection();
            t=app.mylist_data.GetFirstSelection();
            app.mylist_con.Clear();
            for it=1:size(app.controlt,1)%寻找控制信息 用变量名寻
                if strcmp(t,app.controlt{it,1})
                    c=app.controlt{it,2};
                    app.mylist_con.Append(c);
                end
            end
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            %添加控制信息
            
            %检查变量名是否一致
            if ~strcmp(app.mylist_data.GetFirstSelection(),app.Label_9.Text)
                msgbox('变量名不一致','警告','warn');
                return;
            end
            
            t=app.mylist_data.GetFirstSelection();t=t{1};
            t1=app.DropDown.Value;
            for it=1:size(app.controlt,1)%寻找控制信息 用变量名寻 先查看是否有这个控制信息
                if strcmp(t,app.controlt{it,1}) &&strcmp(t1,app.controlt{it,2})
                    msgbox('已有此控制信息','警告','warn');
                    return;%已经有了就返回
                end
%                 if it==size(app.controlt,1)
%                     msgbox('无此变量的控制信息','警告','warn');
%                 end
            end
            app.mylist_con.Append(app.DropDown.Value);
            app.controlt=[app.controlt;{t t1}];
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            %删除控制信息
            %检查变量名是否一致
            if ~strcmp(app.mylist_data.GetFirstSelection(),app.Label_9.Text)
                msgbox('变量名不一致','警告','warn');
                return;
            end
            
            t=app.mylist_data.GetFirstSelection();t=t{1};
            t1=app.mylist_con.GetFirstSelection();t1=t1{1};
            for it=1:size(app.controlt,1)%寻找控制信息 用变量名寻 先查看是否有这个控制信息
                if strcmp(t,app.controlt{it,1}) &&strcmp(t1,app.controlt{it,2})
                    app.controlt(it,:)=[];%删除信息
                    app.mylist_con.DeleteSelection();
                    return;
                end
                

            end
            error('未找到此条控制信息,建议重启。');
        end

        % Callback function
        function Button_12Pushed(app, event)
            %移动控制信息
            %检查变量名是否一致
            if ~strcmp(app.mylist_data.GetFirstSelection(),app.Label_9.Text)
                msgbox('变量名不一致','警告','warn');
                return;
            end
            app.mylist_con.MoveUp();
            t=app.mylist_data.GetFirstSelection();
            for it=1:size(app.controlt,1)%寻找控制信息 用变量名寻
                if strcmp(t,app.controlt{it,1})
                    app.controlt{it,2}=app.mylist_con.items;
                    break;
                end
                if it==size(app.controlt,1)
                    msgbox('无此变量的控制信息','警告','warn');
                end
            end
            
        end

        % Callback function
        function Button_13Pushed(app, event)
            %移动控制信息
            %检查变量名是否一致
            if ~strcmp(app.mylist_data.GetFirstSelection(),app.Label_9.Text)
                msgbox('变量名不一致','警告','warn');
                return;
            end
            app.mylist_con.MoveDown();
            t=app.mylist_data.GetFirstSelection();
            for it=1:size(app.controlt,1)%寻找控制信息 用变量名寻
                if strcmp(t,app.controlt{it,1})
                    app.controlt{it,2}=app.mylist_con.items;
                    break;
                end
                if it==size(app.controlt,1)
                    msgbox('无此变量的控制信息','警告','warn');
                end
            end
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            %             app.control={};
            %排序控制信息
            %             for it=1:length(app.mylist_data.items)
            %                 t=app.mylist_data.items(it);
            %                 for k=1:size(app.controlt,1)
            %                     if strcmp(t,app.controlt{k,1})
            %                         app.control=[app.control;app.controlt(k,:)];
            %
            %                     end
            %                 end
            %             end
            rowformat={app.rowformat1,app.rowformat2,app.rowformat3};
            if app.CheckBox.Value==1
                flhz_x(app.EditField.Value,app.DropDown_DataSheet.Value,rowformat,app.mylist_class.items,app.controlt,app.EditField_3.Value,1);
            else
                flhz_x(app.EditField.Value,app.DropDown_DataSheet.Value,rowformat,app.mylist_class.items,app.controlt,app.EditField_3.Value);
            end
            uiwait(msgbox('分类汇总完成','成功','modal'));
            
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            %保存配置到lastcon
            fid=fopen('lastcon.txt','w');
            t=app.PathRewrite(app.EditField.Value)
            fprintf(fid,t);
            fprintf(fid,'\r\n');
            fprintf(fid,app.DropDown_DataSheet.Value);
            fprintf(fid,'\r\n');
            fprintf(fid,app.FormStr(app.mylist_class.items));%写入分类变量
            fprintf(fid,'\r\n');
            fprintf(fid,app.FormStr2(app.controlt));%写入控制信息
            fprintf(fid,'\r\n');
            fprintf(fid,app.EditField_3.Value);
            fprintf(fid,'\r\n');
            fprintf(fid,app.FormStr(app.mylist_unused.items));
            fprintf(fid,'\r\n');
            %保存rowformat信息
            fprintf(fid,'[%d',app.rowformat1);
            fprintf(fid,']\r\n');
            fprintf(fid,'[');
            for it=1:length(app.rowformat2)
                fprintf(fid,' %d',app.rowformat2(it));
            end
            fprintf(fid,']\r\n');
            fprintf(fid,'[%d',app.rowformat3);
            fprintf(fid,']\r\n');
            fclose(fid);
            uiwait(msgbox('保存配置完成'));
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            %载入配置
            fid=fopen('lastcon.txt','r');
            ln=fgetl(fid);
            app.EditField.Value=ln;
            ln=fgetl(fid);
            app.DropDown_DataSheet.Value=ln;
            ln=fgetl(fid);
            eval(['c=' ln ';']);
            app.mylist_class.LoadFromCell(c);
            ln=fgetl(fid);
            eval(['c=' ln ';']);
            app.controlt=c;
            c=c(:,1);c=unique(c);
            app.mylist_data.LoadFromCell(c);
            
            ln=fgetl(fid);
            app.EditField_3.Value=ln;
            ln=fgetl(fid);
            eval(['c=' ln ';']);
            app.mylist_unused.LoadFromCell(c);
            %rowformat
            ln=fgetl(fid);
            eval(['c=' ln ';']);
            app.EditField_4.Value=num2str(c);
            app.EditField_4ValueChanged([]);
            ln=fgetl(fid);
            eval(['c=' ln ';']);
            for it=1:length(c)
                app.EditField_5.Value=[app.EditField_5.Value ' ' num2str(c(it))];
            end
            app.EditField_5ValueChanged([]);
            ln=fgetl(fid);
            eval(['c=' ln ';']);
            app.EditField_6.Value=num2str(c);
            app.EditField_6ValueChanged([]);
            fclose(fid);
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            %打印控制信息
            app.controlt
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.rowformat1=str2num(value);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            eval(['value=[' value ']']);
            app.rowformat2=value;
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.rowformat3=str2num(value);
        end

        % Button pushed function: Button_SheetFromFile
        function Button_SheetFromFilePushed(app, event)
            [~,sheetname,~]=xlsfinfo(app.EditField.Value);
            app.DropDown_DataSheet.Items=sheetname;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '分类汇总';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '文件';
            app.Button_GetFile = uibutton(app.Panel, 'push');
            app.Button_GetFile.ButtonPushedFcn = createCallbackFcn(app, @Button_GetFilePushed, true);
            app.Button_GetFile.Text = '获取文件';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '获取变量名';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '文件名';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.Value = 't.xlsx';
            app.Button_19 = uibutton(app.Panel, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '载入配置';
            app.Button_18 = uibutton(app.Panel, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '保存配置';
            app.Button_17 = uibutton(app.Panel, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '分类汇总';
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Text = '新建表名';
            app.EditField_3 = uieditfield(app.Panel, 'text');
            app.EditField_3.Value = '汇总表';
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.Text = '写入汇总方式';
            app.Panel_4 = uipanel(app.Panel);
            app.Panel_4.Tooltip = {'输入数据行格式信息。如若不输入，默认变量名第一行，注释行没有，数据行第二行。'};
            app.Panel_4.Title = '数据行格式';
            app.Label_11 = uilabel(app.Panel_4);
            app.Label_11.Text = '变量名';
            app.EditField_4 = uieditfield(app.Panel_4, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Tooltip = {'变量名所在行'};
            app.Label_12 = uilabel(app.Panel_4);
            app.Label_12.Text = '注释行';
            app.EditField_5 = uieditfield(app.Panel_4, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Tooltip = {'注释行 多行时用空格隔开'};
            app.Label_13 = uilabel(app.Panel_4);
            app.Label_13.Text = '数据';
            app.EditField_6 = uieditfield(app.Panel_4, 'text');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Tooltip = {'数据起始行'};
            app.Button_SheetFromFile = uibutton(app.Panel, 'push');
            app.Button_SheetFromFile.ButtonPushedFcn = createCallbackFcn(app, @Button_SheetFromFilePushed, true);
            app.Button_SheetFromFile.Text = '从文件中获取';
            app.Label_14 = uilabel(app.Panel);
            app.Label_14.Text = '数据表';
            app.DropDown_DataSheet = uidropdown(app.Panel);
            app.DropDown_DataSheet.Items = {};
            app.DropDown_DataSheet.Editable = 'on';
            app.DropDown_DataSheet.Value = {};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '变量';
            app.Button_3 = uibutton(app.Panel_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '>>';
            app.Button_4 = uibutton(app.Panel_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '>>';
            app.Button_5 = uibutton(app.Panel_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '<<';
            app.Button_6 = uibutton(app.Panel_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '<<';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '所有剩余变量';
            app.ListBox = uilistbox(app.Panel_2);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '分类变量';
            app.ListBox_2 = uilistbox(app.Panel_2);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '数据变量';
            app.ListBox_3 = uilistbox(app.Panel_2);
            app.ListBox_3.Items = {};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.Value = {};
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '汇总方式';
            app.Button_11 = uibutton(app.Panel_3, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '×';
            app.Button_14 = uibutton(app.Panel_3, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '>>';
            app.Label_8 = uilabel(app.Panel_3);
            app.Label_8.Text = '数据变量:';
            app.Label_9 = uilabel(app.Panel_3);
            app.Label_9.Text = '无';
            app.Button_16 = uibutton(app.Panel_3, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '刷新';
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '汇总方式';
            app.DropDown = uidropdown(app.Panel_3);
            app.DropDown.Items = {'max', 'min', 'absmax', 'absmin', 'count', 'absmax1', 'absmin1'};
            app.DropDown.Value = 'max';
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = '已选择汇总方式';
            app.ListBox_4 = uilistbox(app.Panel_3);
            app.ListBox_4.Items = {};
            app.ListBox_4.Value = {};
            app.Button_20 = uibutton(app.Panel_3, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '打印';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FLHZ

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end